classdef v8_SARS_CoV_2_1AprSM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SARSCoV2ModelUIFigure           matlab.ui.Figure
        GUITabs                         matlab.ui.container.TabGroup
        ModelManipulationTab            matlab.ui.container.Tab
        GenerateNewSpreadsheetsforParametersandSpeciesPanel  matlab.ui.container.Panel
        ParametersPanel                 matlab.ui.container.Panel
        ParametersFileTypeLabel         matlab.ui.control.Label
        ParameterSelectFileTypeDropDown  matlab.ui.control.DropDown
        GenerateParameterSpreadsheetButton  matlab.ui.control.Button
        ParametersFileNameEditFieldLabel  matlab.ui.control.Label
        ParametersFileNameEditField     matlab.ui.control.EditField
        SpeciesPanel                    matlab.ui.container.Panel
        SpeciesFileNameEditFieldLabel   matlab.ui.control.Label
        SpeciesFileNameEditField        matlab.ui.control.EditField
        FileTypeLabel                   matlab.ui.control.Label
        SpeciesSelectFileTypeDropDown   matlab.ui.control.DropDown
        GenerateSpeciesSpreadsheetButton  matlab.ui.control.Button
        TemplateModelEditFieldLabel     matlab.ui.control.Label
        TemplateModel                   matlab.ui.control.EditField
        GUIv81AprLabel                  matlab.ui.control.Label
        Model_Customization             matlab.ui.container.TabGroup
        DefaultModelTab                 matlab.ui.container.Tab
        UseDefaultConfigurationButton   matlab.ui.control.Button
        Default_Model_Info              matlab.ui.control.EditField
        OrModifytheModeltousecustomvaluesforparametersandspeciesLabel  matlab.ui.control.Label
        CustomModelTab                  matlab.ui.container.Tab
        Step1UpdateModelPanel           matlab.ui.container.Panel
        UpdateModelNameButton           matlab.ui.control.Button
        ModelNameDropDownLabel          matlab.ui.control.Label
        ModelNameDropDown               matlab.ui.control.DropDown
        RefreshModelsButton             matlab.ui.control.Button
        YouwillnolongerbeusingthevalueswhichwedeterminedbestLabel  matlab.ui.control.Label
        Step3ImportSpeciesInitialConditionPanel  matlab.ui.container.Panel
        PerformSpeciesModificationButton  matlab.ui.control.Button
        SpeciesSpreadsheetNameDropDownLabel  matlab.ui.control.Label
        SpeciesSpreadsheetNameDropDown  matlab.ui.control.DropDown
        RefreshSpeciesButton            matlab.ui.control.Button
        Step2ImportParameterRateConstantsPanel  matlab.ui.container.Panel
        PerformParameterModificationButton  matlab.ui.control.Button
        ParameterSpreadsheetNameDropDownLabel  matlab.ui.control.Label
        ParameterSpreadsheetNameDropDown  matlab.ui.control.DropDown
        RefreshParameterButton          matlab.ui.control.Button
        ModelBuilderButton              matlab.ui.control.Button
        ModelAnalyzerButton             matlab.ui.control.Button
        CAUTIONThismightbeamorecomplextoolthanyouneedbeawareLabel  matlab.ui.control.Label
        InnateImmuneResponsetoSARSCoV2InfectioninHumansLabel  matlab.ui.control.Label
        PatrickCharltonHannahRuMabryStuartMcKnightTrevorWoodruffLabel  matlab.ui.control.Label
        VirusImage                      matlab.ui.control.Image
        ParameterModification           matlab.ui.container.Tab
        ParameterTable                  matlab.ui.control.Table
        ParametersLabel                 matlab.ui.control.Label
        UpdateParametersButton          matlab.ui.control.Button
        SpeciesTable                    matlab.ui.control.Table
        UpdateSpeciesButton             matlab.ui.control.Button
        SpeciesLabel                    matlab.ui.control.Label
        SolvingtheModelTab              matlab.ui.container.Tab
        SolvingtheModelLabel            matlab.ui.control.Label
        SpecifyInitialViralExposurePanel  matlab.ui.container.Panel
        ViralDoseExposureEditFieldLabel  matlab.ui.control.Label
        ViralDoseExposureEditField      matlab.ui.control.NumericEditField
        TimeofViralExposureEditFieldLabel  matlab.ui.control.Label
        TimeofViralExposureEditField    matlab.ui.control.NumericEditField
        VirusInBodyBeforeExposureEditFieldLabel  matlab.ui.control.Label
        VirusInBodyBeforeExposureEditField  matlab.ui.control.NumericEditField
        AddViralDoseButton              matlab.ui.control.Button
        RemoveViralDoseButton           matlab.ui.control.Button
        hoursLabel                      matlab.ui.control.Label
        molarLabel                      matlab.ui.control.Label
        viruscellsLabel                 matlab.ui.control.Label
        RunModelSimulationPanel         matlab.ui.container.Panel
        RunModelSimulationButton        matlab.ui.control.Button
        ResultsFileNameEditFieldLabel   matlab.ui.control.Label
        ResultsFileNameEditField        matlab.ui.control.EditField
        ExportasCSVButton               matlab.ui.control.Button
        ModelSolvingUpdate              matlab.ui.control.EditField
        SolverSetup                     matlab.ui.container.TabGroup
        DefaultSolverTab                matlab.ui.container.Tab
        SpecifyandUseDefaultSettingsLabel  matlab.ui.control.Label
        StopTimeEditFieldLabel          matlab.ui.control.Label
        StopTimeEditFieldDefault        matlab.ui.control.NumericEditField
        SetDefualtSolverButton          matlab.ui.control.Button
        hoursLabel_2                    matlab.ui.control.Label
        AdvanceSolverSetupTab           matlab.ui.container.Tab
        SpecifyAdvancedSolverParametersLabel  matlab.ui.control.Label
        AbsoluteToleranceSclaingDropDownLabel  matlab.ui.control.Label
        AbsoluteToleranceSclaingDropDown  matlab.ui.control.DropDown
        SetAdvancedSettingsButton       matlab.ui.control.Button
        SolverOptionsLabel              matlab.ui.control.Label
        SolverTypeDropDownLabel         matlab.ui.control.Label
        SolverTypeDropDown              matlab.ui.control.DropDown
        AbsoluteToleranceEditFieldLabel  matlab.ui.control.Label
        AbsoluteToleranceEditField      matlab.ui.control.NumericEditField
        RelativeToleranceEditFieldLabel  matlab.ui.control.Label
        RelativeToleranceEditField      matlab.ui.control.NumericEditField
        SensitivityAnalysisDropDownLabel  matlab.ui.control.Label
        SensitivityAnalysisDropDown     matlab.ui.control.DropDown
        StopTimeLabel                   matlab.ui.control.Label
        StopTimeEditFieldAdvanced       matlab.ui.control.NumericEditField
        WallClocksecEditFieldLabel      matlab.ui.control.Label
        WallClockEditField              matlab.ui.control.NumericEditField
        ModelFeatures                   matlab.ui.container.TabGroup
        SolverTab                       matlab.ui.container.Tab
        SolverTypeLabel                 matlab.ui.control.Label
        ConfirmSolverTypeEditField      matlab.ui.control.EditField
        StopTimeLabel_2                 matlab.ui.control.Label
        ConfirmStopTimeEditField        matlab.ui.control.EditField
        ConfigurationLabel              matlab.ui.control.Label
        SolverOptionsLabel_2            matlab.ui.control.Label
        AbsolutToleranceLabel           matlab.ui.control.Label
        ConfirmAbsoluteToleranceEditField  matlab.ui.control.EditField
        AbsoluteToleranceScalingLabel   matlab.ui.control.Label
        ConfirmAbsoluteToleranceScalingEditField  matlab.ui.control.EditField
        RelativeToleranceLabel          matlab.ui.control.Label
        ConfirmRelativeToleranceEditField  matlab.ui.control.EditField
        SensitivityAnalysisLabel        matlab.ui.control.Label
        ConfirmSensitivityAnalysisEditField  matlab.ui.control.EditField
        WallClockLabel                  matlab.ui.control.Label
        ConfirmWallClockEditField       matlab.ui.control.EditField
        ThesearetheoptionsyouarecurrentlyrunningLabel  matlab.ui.control.Label
        ExposureTab                     matlab.ui.container.Tab
        InitialVirusInBodyLabel         matlab.ui.control.Label
        ConfirmVirusInBodyEditField     matlab.ui.control.EditField
        InitialVirusLabel               matlab.ui.control.Label
        ViralExposureLabel              matlab.ui.control.Label
        ViralDoseLabel                  matlab.ui.control.Label
        ConfirmViralDoseAmountEditField  matlab.ui.control.EditField
        TimeofExposureLabel             matlab.ui.control.Label
        ConfirmTimeOfExposureEditField  matlab.ui.control.EditField
        TargetSpeciesLabel              matlab.ui.control.Label
        ConfirmTargetSpeciesEditField   matlab.ui.control.EditField
        ThesearetheexposureoptionsyouarecurrentlyrunningLabel  matlab.ui.control.Label
        PlottingTab                     matlab.ui.container.Tab
        XMinSliderLabel                 matlab.ui.control.Label
        XMinSlider                      matlab.ui.control.Slider
        XMaxSliderLabel                 matlab.ui.control.Label
        XMaxSlider                      matlab.ui.control.Slider
        ConcOrCountDropDown             matlab.ui.control.DropDown
        SeeLargerPlotButton             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        PlottingtheModelTab             matlab.ui.container.Tab
        XMinSlider_2Label               matlab.ui.control.Label
        XMinSpeciesPlot                 matlab.ui.control.Slider
        XMaxSlider_2Label               matlab.ui.control.Label
        XMaxSpeciesPlot                 matlab.ui.control.Slider
        YMinSliderLabel                 matlab.ui.control.Label
        YMinSpeciesPlot                 matlab.ui.control.Slider
        YMaxSliderLabel                 matlab.ui.control.Label
        YMaxSpeciesPlot                 matlab.ui.control.Slider
        PlotTypeTab                     matlab.ui.container.TabGroup
        DefaultPlotTab                  matlab.ui.container.Tab
        PlotDefaultButton               matlab.ui.control.Button
        MasterPlotDefaultConcOrCountDropDown  matlab.ui.control.DropDown
        CustomSpeciesPlotTab            matlab.ui.container.Tab
        SelectSpeciesctrlcmdtoselectmultipleLabel  matlab.ui.control.Label
        Species                         matlab.ui.control.ListBox
        MasterPlotCustomConcOrCountDropDown  matlab.ui.control.DropDown
        ExportPlotButton                matlab.ui.control.Button
        SavePlotButton                  matlab.ui.control.Button
        FilenameEditFieldLabel          matlab.ui.control.Label
        SavePlotFileNameEditField       matlab.ui.control.EditField
        AxesSpeciesPlot                 matlab.ui.control.UIAxes
        VisualSensitivityTab            matlab.ui.container.Tab
        SensPlottingTab                 matlab.ui.container.TabGroup
        SensitivitySolverTab            matlab.ui.container.Tab
        SensParamTable                  matlab.ui.control.Table
        UpdateSolverButton              matlab.ui.control.Button
        UpdateExposureButton            matlab.ui.control.Button
        ResetParametersButton           matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        ModifyParameterValuesSameUnitsasAboveLabel  matlab.ui.control.Label
        AddRowSensParamsTableButton     matlab.ui.control.Button
        RemoveRowSensParamsTableButton  matlab.ui.control.Button
        SelectParametersctrlcmdtoselectmultipleLabel  matlab.ui.control.Label
        SensParameters                  matlab.ui.control.ListBox
        SensSolvingUpdateEditField      matlab.ui.control.EditField
        SelectingtoomanyparameterscanresultinlongruntimesLabel  matlab.ui.control.Label
        SensSpeciesToPlotTab            matlab.ui.container.Tab
        SelectSpeciesLabel              matlab.ui.control.Label
        SensSpecies                     matlab.ui.control.ListBox
        SensPlotConcOrCountDropDown     matlab.ui.control.DropDown
        ExportSensPlotButton            matlab.ui.control.Button
        SaveSensPlotButton              matlab.ui.control.Button
        YMaxSliderLabel_2               matlab.ui.control.Label
        YMaxSensPlot                    matlab.ui.control.Slider
        FilenameEditFieldLabel_2        matlab.ui.control.Label
        SaveSensPlotFileNameEditField   matlab.ui.control.EditField
        XMinSensPlot                    matlab.ui.control.Slider
        XMinSlider_2Label_2             matlab.ui.control.Label
        XMaxSensPlot                    matlab.ui.control.Slider
        XMaxSlider_2Label_2             matlab.ui.control.Label
        YMaxSliderLabel_3               matlab.ui.control.Label
        YMinSensPlot                    matlab.ui.control.Slider
        UISensAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %Model = sbioloadproject("Models/Default Model/SM_Update_NOV29.sbproj").m1; % Description
        Model = [];
        SolverProperties = [];
        ExposureProperties = [];
        %SolverProperties = getconfigset(sbioloadproject("Models/Default Model/SM_Update_NOV29.sbproj").m1)
        Results = [];
        ModelReactionInformation = [];
        ModelSpeciesInformation = [];
        TimeUnits = "hours";
        CellVolume = [];
        AvogadrosNumber = 6.02214076e23;
        MasterPlot = [];
        ParameterIndexToChange = [];
        ParameterNewValueToChage = [];
        SpeciesIndexToChange = [];
        SpeciesNewValueToChage = [];
        Version = 8;
        SensRxnIndexToChange = [];
        SensParmIndexToChange = [];
        SensParametersString = [];
        SensResults = [];
        SensSpeciesToPlot = [];
        SimErrorHappen = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SARSCoV2ModelUIFigure.Visible = 'off';
            tpause = 0.7;
            f = waitbar(0.1,'Resetting Path...','Visible','off');
            f.Visible = "on";
            pause(tpause)
            path(pathdef)
            
            waitbar(0.33,f,'Loading Path to GUI codes...');
            pause(tpause)
            % Set Paths for files
            version = app.Version;
            str_version = sprintf("GUI_src/v%s",string(version));
            addpath("GUI_src/")
            addpath([char(str_version),'/'])
            addpath([char(str_version),'/Start Up/'])
            addpath([char(str_version),'/Plotting/'])
            addpath([char(str_version),'/Default Model/'])
            addpath([char(str_version),'/ParameterAndSpeciesTab/'])
            addpath([char(str_version),'/ModelSolving/'])   
            addpath([char(str_version),'/ParameterModification/']) 
            addpath([char(str_version),'/VisualSensitivity/'])
            addpath("Models/")
            addpath("SpeciesParameterConventions/")
            addpath("SpeciesParameterConventions/Default Values/")
            addpath("Model Generated Data/") 
            addpath("Model Generated Plots/")    
            if isempty(app.VirusImage.ImageSource)
                app.VirusImage.ImageSource = imread('coronavirus-covid-19.png');
            end
            % Populate File Drop Downs
            waitbar(0.66,f,'Populating Dropdowns...');
            pause(tpause)
            Display_and_Update_Model_Names(app)
            Display_and_Update_Spreadsheet_Names(app)
            
            % Resize GUI
            waitbar(1,f,'Displaying GUI...');
            pause(tpause)
            % Set GUI screensize            
            ScreenDim = get(groot,'ScreenSize');
            ScreenWidth = ScreenDim(3);
            ScreenHeight = ScreenDim(4);
            drawnow;
            app.SARSCoV2ModelUIFigure.Position = ...
                [ScreenWidth*0.05 ScreenHeight*0.05 ScreenWidth*0.9 ScreenHeight*0.9];
            
            delete(f)
            app.SARSCoV2ModelUIFigure.Visible = 'on';
            
        end

        % Callback function
        function PerformParameterModificationButtonPushed(app, event)
            
        end

        % Button pushed function: UpdateModelNameButton
        function UpdateModelNameButtonPushed(app, event)
            app.Model = sbioloadproject(app.ModelNameDropDown.Value).m1;
            app.TemplateModel.Value = string(app.Model.Name);
            disp('------------------------------------------')
            fprintf('Model updated to %s\n',string(app.Model.Name))
            disp('------------------------------------------')
            GenerateReactionIndexes_Name_Value_ParameterIndex(app);
            PopulateParameterTable(app);
            GenerateSpeciesIndex_Name_Value(app);
            PopulateSpeciesTable(app);
            RemoveViralDose(app); %remove any viral doses that are linked to the .sbproj file that might confuse the dosing
            app.CellVolume = app.Model.Compartments(2).Value;
        end

        % Callback function
        function PerformSpeciesModificationButtonPushed(app, event)
            
        end

        % Button pushed function: ModelBuilderButton
        function ModelBuilderButtonPushed(app, event)
            simBiologyModelBuilder(app.Model)
        end

        % Button pushed function: ModelAnalyzerButton
        function ModelAnalyzerButtonPushed(app, event)
            simBiologyModelAnalyzer(app.Model)
        end

        % Button pushed function: SetDefualtSolverButton
        function SetDefualtSolverButtonPushed(app, event)
            DefaultSolverSetup(app)
            ConfirmSolverTab(app)
            app.ModelFeatures.SelectedTab = app.SolverTab;
        end

        % Button pushed function: SetAdvancedSettingsButton
        function SetAdvancedSettingsButtonPushed(app, event)
            AdvancedSolverSetup(app)
            ConfirmSolverTab(app)
            app.ModelFeatures.SelectedTab = app.SolverTab;
        end

        % Button pushed function: AddViralDoseButton
        function AddViralDoseButtonPushed(app, event)
            ViralExposure(app)
            ConfirmExposureTab(app)
            app.ModelFeatures.SelectedTab = app.ExposureTab;
        end

        % Button pushed function: RunModelSimulationButton
        function RunModelSimulationButtonPushed(app, event)
            RunModelSimulation(app)
            if app.SimErrorHappen == 0
                SARS_CoV_2Plot(app)
                app.Species.Items = app.Results.Names;
                ParameterNames(app);
                app.ModelFeatures.SelectedTab = app.PlottingTab;
                ParameterNames(app);
                app.SensPlottingTab.SelectedTab = app.SensitivitySolverTab;
            end
            if app.SimErrorHappen == 1
               disp('Try new parameters or different solver.')
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ExportasCSVButton
        function ExportasCSVButtonPushed(app, event)
            ExportResultsAsCSV(app)
        end

        % Button pushed function: RemoveViralDoseButton
        function RemoveViralDoseButtonPushed(app, event)
            RemoveViralDose(app)
            ConfirmExposureTab(app)
            app.ModelFeatures.SelectedTab = app.ExposureTab;
        end

        % Callback function: XMinSlider, XMinSlider
        function XMinSliderValueChanged(app, event)
            %value = app.XMinSlider.Value;
            SARS_CoV_2Plot(app)
        end

        % Callback function: XMaxSlider, XMaxSlider
        function XMaxSliderValueChanged(app, event)
            %value = app.XMaxSlider.Value;
            SARS_CoV_2Plot(app)
        end

        % Value changed function: 
        % MasterPlotCustomConcOrCountDropDown, Species
        function SpeciesValueChanged(app, event)
            MasterPlotCustom(app)
        end

        % Value changed function: XMaxSpeciesPlot, XMinSpeciesPlot
        function XMinSpeciesPlotValueChanged(app, event)
            try
                xlim(app.AxesSpeciesPlot,[app.XMinSpeciesPlot.Value,app.XMaxSpeciesPlot.Value])
            catch
                warning('Limits must be Increasing from Minimum to Maximum')
            end
        end

        % Callback function: MasterPlotDefaultConcOrCountDropDown, 
        % PlotDefaultButton
        function PlotDefaultButtonPushed(app, event)
            MasterPlotDefault(app)
        end

        % Value changed function: YMaxSpeciesPlot, YMinSpeciesPlot
        function YMinSpeciesPlotValueChanged(app, event)
            try
               ylim(app.AxesSpeciesPlot,[app.YMinSpeciesPlot.Value,app.YMaxSpeciesPlot.Value])
            catch
                warning('Limits must be Increasing from Minimum to Maximum')
            end
        end

        % Button pushed function: GenerateParameterSpreadsheetButton
        function GenerateParameterSpreadsheetButtonPushed(app, event)
            NewParameterSheet(app);
        end

        % Button pushed function: PerformParameterModificationButton
        function PerformParameterModificationButton_2Pushed(app, event)
            f = uifigure;
            f.WindowStyle = 'modal';
            f.Units = 'normalized';
            f.Position = [0.3,0.3,0.3,0.18];
            msg = 'Warning: You are about to over-ride our values which were found through research and fitting to clinical data!';
            confirm = uiconfirm(f,msg,'Confirm Parameter Change',...
                'Options',{'Overwrite','Cancel'},...
                'DefaultOption',1,'CancelOption',2,...
                'Icon','warning');
            
            if string(confirm) == "Overwrite"
                delete(f)
                ParameterModification_v2(app)
                GenerateReactionIndexes_Name_Value_ParameterIndex(app);
                PopulateParameterTable(app);
            end
            delete(f)
        end

        % Button pushed function: PerformSpeciesModificationButton
        function PerformSpeciesModificationButton_2Pushed(app, event)
            f = uifigure;
            f.WindowStyle = 'modal';
            f.Units = 'normalized';
            f.Position = [0.3,0.3,0.3,0.18];
            msg = 'Warning: You are about to over-ride our values which were found through research and fitting to clinical data!';
            confirm = uiconfirm(f,msg,'Confirm Species Change',...
                'Options',{'Overwrite','Cancel'},...
                'DefaultOption',1,'CancelOption',2,...
                'Icon','warning');
           
            if string(confirm) == "Overwrite"
                delete(f)
                SpeciesModification_v2(app)
                GenerateSpeciesIndex_Name_Value(app);
                PopulateSpeciesTable(app);                
            end
            delete(f)
        end

        % Button pushed function: GenerateSpeciesSpreadsheetButton
        function GenerateSpeciesSpreadsheetButtonPushed(app, event)
            NewSpeciesSheet(app)
        end

        % Callback function
        function RefreshFolderContentsButtonPushed(app, event)
            
        end

        % Drop down opening function: 
        % ParameterSpreadsheetNameDropDown
        function ParameterSpreadsheetNameDropDownOpening(app, event)
            Display_and_Update_Spreadsheet_Names(app)
        end

        % Drop down opening function: SpeciesSpreadsheetNameDropDown
        function SpeciesSpreadsheetNameDropDownOpening(app, event)
            Display_and_Update_Spreadsheet_Names(app)
        end

        % Drop down opening function: ModelNameDropDown
        function ModelNameDropDownOpening(app, event)
            Display_and_Update_Model_Names(app)
        end

        % Button pushed function: UseDefaultConfigurationButton
        function UseDefaultConfigurationButtonPushed(app, event)
            BuildDefaultModel(app)
            app.TemplateModel.Value = string(app.Model.Name);
            GenerateReactionIndexes_Name_Value_ParameterIndex(app);
            GenerateSpeciesIndex_Name_Value(app);
            PopulateParameterTable(app);
            PopulateSpeciesTable(app);
            RemoveViralDose(app); %remove any viral doses that are linked to the .sbproj file that might confuse the dosing
            app.CellVolume = app.Model.Compartments(2).Value;
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
            f = uifigure;
            f.WindowStyle = 'modal';
            f.Units = 'normalized';
            f.Position = [0.3,0.3,0.3,0.18];
            msg = 'Warning: You are about to over-ride our values which were found through research and fitting to clinical data!';
            confirm = uiconfirm(f,msg,'Confirm Parameter Change',...
                'Options',{'Overwrite','Cancel'},...
                'DefaultOption',1,'CancelOption',2,...
                'Icon','warning');
            %uiwait(msgbox('Warning: You are about to over-ride our values which were found through research and fitting to clinical data!', 'Warning','warn','replace'));            
            if string(confirm) == "Overwrite"
                ParameterModification_FromTable(app)
            end
            delete(f)
        end

        % Button pushed function: UpdateSpeciesButton
        function UpdateSpeciesButtonPushed(app, event)
            f = uifigure;
            f.WindowStyle = 'modal';
            f.Units = 'normalized';
            f.Position = [0.3,0.3,0.3,0.18];
            msg = 'Warning: You are about to over-ride our values which were found through research and fitting to clinical data!';
            confirm = uiconfirm(f,msg,'Confirm Species Change',...
                'Options',{'Overwrite','Cancel'},...
                'DefaultOption',1,'CancelOption',2,...
                'Icon','warning');
            if string(confirm) == "Overwrite"
                SpeciesModification_FromTable(app)
            end
            delete(f)
        end

        % Value changed function: ConcOrCountDropDown
        function ConcOrCountDropDownValueChanged(app, event)
            SARS_CoV_2Plot(app)
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            ExportMasterPlot(app)
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            SaveMasterPlot(app)
        end

        % Cell edit callback: ParameterTable
        function ParameterTableCellEdit(app, event)
            Index = event.Indices(1);
            NewData = event.NewData;
            % Provide logic to prevent the user from inputting non-real
            % parameter values
            if isreal(str2double(NewData)) && ~isnan(str2double(NewData))
                ParameterTableIndexAndValueToUpdate(app, Index, str2double(NewData))
            else
                str_error = sprintf('Must input real numbers for Parameter Value!\n " %s " is not a real number.\n Try again...',NewData);
                uiwait(errordlg(str_error,'Non-real Number','replace'))
                app.ParameterTable.Data(Index,4) = app.ModelReactionInformation.Value(Index);
            end
            figure(app.SARSCoV2ModelUIFigure)
        end

        % Cell edit callback: SpeciesTable
        function SpeciesTableCellEdit(app, event)
            Index = event.Indices(1);
            NewData = event.NewData;
            % Provide logic to prevent the user from inputting non-real
            % species values
            if isreal(str2double(NewData)) && ~isnan(str2double(NewData))
                SpeciesTableIndexAndValueToUpdate(app, Index, str2double(NewData))
            else
                str_error = sprintf('Must input real numbers for Species Value!\n " %s " is not a real number.\n Try again...',NewData);
                uiwait(errordlg(str_error,'Non-real Number','replace'))
                app.SpeciesTable.Data(Index,3) = app.ModelSpeciesInformation.Initial_Amount(Index);
            end
            figure(app.SARSCoV2ModelUIFigure)
        end

        % Button pushed function: SeeLargerPlotButton
        function SeeLargerPlotButtonPushed(app, event)
            app.GUITabs.SelectedTab = app.PlottingtheModelTab;
            app.PlotTypeTab.SelectedTab = app.DefaultPlotTab;
            PlotDefaultButtonPushed(app, event)
        end

        % Value changed function: SensParameters
        function SensParametersValueChanged(app, event)
            %value = app.SensParameters.Value;
            PopulateSensParameterTable(app);
        end

        % Callback function
        function AddRowSensParamsTableButtonPushed(app, event)
            AddRowSensParamsTableButtonPushed(app);
        end

        % Button pushed function: UpdateSolverButton
        function UpdateSolverButtonPushed(app, event)
            app.GUITabs.SelectedTab = app.SolvingtheModelTab;
        end

        % Button pushed function: UpdateExposureButton
        function UpdateExposureButtonPushed(app, event)
            app.GUITabs.SelectedTab = app.SolvingtheModelTab;
        end

        % Button pushed function: ResetParametersButton
        function ResetParametersButtonPushed(app, event)
            f = uifigure;
            f.WindowStyle = 'modal';
            f.Units = 'normalized';
            f.Position = [0.3,0.3,0.3,0.18];
            msg = "Warning: You are about to over-ride any modification to the Default Model's Parameters";
            confirm = uiconfirm(f,msg,'Confirm Overwriting Modifications',...
                'Options',{'Overwrite','Cancel'},...
                'DefaultOption',1,'CancelOption',2,...
                'Icon','warning');
            if string(confirm) == "Overwrite"
                UseDefaultConfigurationButtonPushed(app, event);
                figure(app.SARSCoV2ModelUIFigure)
            else
                uiwait(msgbox('No modifcations were made.',"modal"))
                figure(app.SARSCoV2ModelUIFigure)

            end
            delete(f)
           
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            SensSolveSimulations(app)
            app.SensSpecies.Items = app.SensResults.Names{1};
            app.SensSpeciesToPlot = 4;
            SensPlotting(app)
        end

        % Value changed function: SensPlotConcOrCountDropDown
        function SensPlotConcOrCountDropDownValueChanged(app, event)
            SensPlotting(app)
        end

        % Button pushed function: ExportSensPlotButton
        function ExportSensPlotButtonPushed(app, event)
            ExportSensPlot(app)
        end

        % Button pushed function: SaveSensPlotButton
        function SaveSensPlotButtonPushed(app, event)
            SaveSensPlot(app)
        end

        % Cell edit callback: SensParamTable
        function SensParamTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isreal(str2double(newData)) && ~isnan(str2double(newData))
                
            else
                str_error = sprintf('Must input real numbers for Species Value!\n " %s " is not a real number.\n Try again...',newData);
                errordlg(str_error,'Non-real Number','replace')
                app.SensParamTable.Data(indices,2) = " ";
            end
        end

        % Value changed function: SensSpecies
        function SensSpeciesValueChanged(app, event)
            SensSpeciesToPlotIndex(app)
            SensPlotting(app)
        end

        % Value changed function: YMinSensPlot
        function YMinSensPlotValueChanged(app, event)
            value = app.YMinSensPlot.Value;
            app.YMinSensPlot.Value = value;
            try
                ylim(app.UISensAxes,[app.YMinSensPlot.Value,app.YMaxSensPlot.Value])
            catch
                warning('Limits must be Increasing from Minimum to Maximum')
            end
        end

        % Value changed function: YMaxSensPlot
        function YMaxSensPlotValueChanged(app, event)
            value = app.YMaxSensPlot.Value;
            app.YMaxSensPlot.Value = value;
            try
                ylim(app.UISensAxes,[app.YMinSensPlot.Value,app.YMaxSensPlot.Value])
            catch
                warning('Limits must be Increasing from Minimum to Maximum')
            end
        end

        % Value changed function: XMinSensPlot
        function XMinSensPlotValueChanged(app, event)
            value = app.XMinSensPlot.Value;
            app.XMinSensPlot.Value = value;
            try
                xlim(app.UISensAxes,[app.XMinSensPlot.Value,app.XMaxSensPlot.Value])
            catch
                warning('Limits must be Increasing from Minimum to Maximum')
            end            
        end

        % Value changed function: XMaxSensPlot
        function XMaxSensPlotValueChanged(app, event)
            value = app.XMaxSensPlot.Value;
            app.XMaxSensPlot.Value = value;
            try
                xlim(app.UISensAxes,[app.XMinSensPlot.Value,app.XMaxSensPlot.Value])
            catch
                warning('Limits must be Increasing from Minimum to Maximum')
            end              
        end

        % Button pushed function: RefreshModelsButton
        function RefreshModelsButtonPushed(app, event)
            Display_and_Update_Model_Names(app)
        end

        % Button pushed function: RefreshParameterButton
        function RefreshParameterButtonPushed(app, event)
            Display_and_Update_Spreadsheet_Names(app)
        end

        % Button pushed function: RefreshSpeciesButton
        function RefreshSpeciesButtonPushed(app, event)
            Display_and_Update_Spreadsheet_Names(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SARSCoV2ModelUIFigure = uifigure('Visible', 'off');
            app.SARSCoV2ModelUIFigure.Name = 'SARS-CoV-2 Model';
            app.GUITabs = uitabgroup(app.SARSCoV2ModelUIFigure);
            app.ModelManipulationTab = uitab(app.GUITabs);
            app.ModelManipulationTab.Title = 'Model Manipulation';
            app.GenerateNewSpreadsheetsforParametersandSpeciesPanel = uipanel(app.ModelManipulationTab);
            app.GenerateNewSpreadsheetsforParametersandSpeciesPanel.Title = 'Generate New Spreadsheets for Parameters and Species';
            app.ParametersPanel = uipanel(app.GenerateNewSpreadsheetsforParametersandSpeciesPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.ParametersFileTypeLabel = uilabel(app.ParametersPanel);
            app.ParametersFileTypeLabel.Text = 'Parameters File Type';
            app.ParameterSelectFileTypeDropDown = uidropdown(app.ParametersPanel);
            app.ParameterSelectFileTypeDropDown.Items = {'.xlsx', '.xls', '.csv'};
            app.ParameterSelectFileTypeDropDown.Value = '.xlsx';
            app.GenerateParameterSpreadsheetButton = uibutton(app.ParametersPanel, 'push');
            app.GenerateParameterSpreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateParameterSpreadsheetButtonPushed, true);
            app.GenerateParameterSpreadsheetButton.Text = 'Generate Parameter Spreadsheet';
            app.ParametersFileNameEditFieldLabel = uilabel(app.ParametersPanel);
            app.ParametersFileNameEditFieldLabel.Text = 'Parameters File Name';
            app.ParametersFileNameEditField = uieditfield(app.ParametersPanel, 'text');
            app.SpeciesPanel = uipanel(app.GenerateNewSpreadsheetsforParametersandSpeciesPanel);
            app.SpeciesPanel.Title = 'Species';
            app.SpeciesFileNameEditFieldLabel = uilabel(app.SpeciesPanel);
            app.SpeciesFileNameEditFieldLabel.Text = 'Species File Name';
            app.SpeciesFileNameEditField = uieditfield(app.SpeciesPanel, 'text');
            app.FileTypeLabel = uilabel(app.SpeciesPanel);
            app.FileTypeLabel.Text = 'File Type';
            app.SpeciesSelectFileTypeDropDown = uidropdown(app.SpeciesPanel);
            app.SpeciesSelectFileTypeDropDown.Items = {'.xlsx', '.xls', '.csv'};
            app.SpeciesSelectFileTypeDropDown.Value = '.xlsx';
            app.GenerateSpeciesSpreadsheetButton = uibutton(app.SpeciesPanel, 'push');
            app.GenerateSpeciesSpreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSpeciesSpreadsheetButtonPushed, true);
            app.GenerateSpeciesSpreadsheetButton.Text = 'Generate Species Spreadsheet';
            app.TemplateModelEditFieldLabel = uilabel(app.GenerateNewSpreadsheetsforParametersandSpeciesPanel);
            app.TemplateModelEditFieldLabel.Text = 'Template Model';
            app.TemplateModel = uieditfield(app.GenerateNewSpreadsheetsforParametersandSpeciesPanel, 'text');
            app.TemplateModel.Editable = 'off';
            app.GUIv81AprLabel = uilabel(app.ModelManipulationTab);
            app.GUIv81AprLabel.Text = {'GUI v8'; '1Apr'};
            app.Model_Customization = uitabgroup(app.ModelManipulationTab);
            app.DefaultModelTab = uitab(app.Model_Customization);
            app.DefaultModelTab.Title = 'Default Model';
            app.UseDefaultConfigurationButton = uibutton(app.DefaultModelTab, 'push');
            app.UseDefaultConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @UseDefaultConfigurationButtonPushed, true);
            app.UseDefaultConfigurationButton.Text = 'Use Default Configuration';
            app.Default_Model_Info = uieditfield(app.DefaultModelTab, 'text');
            app.Default_Model_Info.Editable = 'off';
            app.OrModifytheModeltousecustomvaluesforparametersandspeciesLabel = uilabel(app.DefaultModelTab);
            app.OrModifytheModeltousecustomvaluesforparametersandspeciesLabel.Text = {'Or... modify the Model to use custom values for parameters and species.'; 'Do this using the Cusom Model tab'};
            app.CustomModelTab = uitab(app.Model_Customization);
            app.CustomModelTab.Title = 'Custom Model';
            app.Step1UpdateModelPanel = uipanel(app.CustomModelTab);
            app.Step1UpdateModelPanel.Title = 'Step 1: Update Model';
            app.UpdateModelNameButton = uibutton(app.Step1UpdateModelPanel, 'push');
            app.UpdateModelNameButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateModelNameButtonPushed, true);
            app.UpdateModelNameButton.Text = 'Update Model Name';
            app.ModelNameDropDownLabel = uilabel(app.Step1UpdateModelPanel);
            app.ModelNameDropDownLabel.Text = 'Model Name';
            app.ModelNameDropDown = uidropdown(app.Step1UpdateModelPanel);
            app.ModelNameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ModelNameDropDownOpening, true);
            app.RefreshModelsButton = uibutton(app.Step1UpdateModelPanel, 'push');
            app.RefreshModelsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshModelsButtonPushed, true);
            app.RefreshModelsButton.Text = 'Refresh';
            app.YouwillnolongerbeusingthevalueswhichwedeterminedbestLabel = uilabel(app.CustomModelTab);
            app.YouwillnolongerbeusingthevalueswhichwedeterminedbestLabel.Text = 'You will no longer be using the values which we determined best!';
            app.Step3ImportSpeciesInitialConditionPanel = uipanel(app.CustomModelTab);
            app.Step3ImportSpeciesInitialConditionPanel.Title = 'Step 3: Import Species Initial Condition ';
            app.PerformSpeciesModificationButton = uibutton(app.Step3ImportSpeciesInitialConditionPanel, 'push');
            app.PerformSpeciesModificationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformSpeciesModificationButton_2Pushed, true);
            app.PerformSpeciesModificationButton.Text = 'Perform Species Modification';
            app.SpeciesSpreadsheetNameDropDownLabel = uilabel(app.Step3ImportSpeciesInitialConditionPanel);
            app.SpeciesSpreadsheetNameDropDownLabel.Text = 'Species Spreadsheet Name';
            app.SpeciesSpreadsheetNameDropDown = uidropdown(app.Step3ImportSpeciesInitialConditionPanel);
            app.SpeciesSpreadsheetNameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SpeciesSpreadsheetNameDropDownOpening, true);
            app.RefreshSpeciesButton = uibutton(app.Step3ImportSpeciesInitialConditionPanel, 'push');
            app.RefreshSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshSpeciesButtonPushed, true);
            app.RefreshSpeciesButton.Text = 'Refresh';
            app.Step2ImportParameterRateConstantsPanel = uipanel(app.CustomModelTab);
            app.Step2ImportParameterRateConstantsPanel.Title = 'Step 2: Import Parameter Rate Constants';
            app.PerformParameterModificationButton = uibutton(app.Step2ImportParameterRateConstantsPanel, 'push');
            app.PerformParameterModificationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformParameterModificationButton_2Pushed, true);
            app.PerformParameterModificationButton.Text = 'Perform Parameter Modification';
            app.ParameterSpreadsheetNameDropDownLabel = uilabel(app.Step2ImportParameterRateConstantsPanel);
            app.ParameterSpreadsheetNameDropDownLabel.Text = 'Parameter Spreadsheet Name';
            app.ParameterSpreadsheetNameDropDown = uidropdown(app.Step2ImportParameterRateConstantsPanel);
            app.ParameterSpreadsheetNameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ParameterSpreadsheetNameDropDownOpening, true);
            app.RefreshParameterButton = uibutton(app.Step2ImportParameterRateConstantsPanel, 'push');
            app.RefreshParameterButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshParameterButtonPushed, true);
            app.RefreshParameterButton.Text = 'Refresh';
            app.ModelBuilderButton = uibutton(app.CustomModelTab, 'push');
            app.ModelBuilderButton.ButtonPushedFcn = createCallbackFcn(app, @ModelBuilderButtonPushed, true);
            app.ModelBuilderButton.Text = 'Model Builder';
            app.ModelAnalyzerButton = uibutton(app.CustomModelTab, 'push');
            app.ModelAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @ModelAnalyzerButtonPushed, true);
            app.ModelAnalyzerButton.Text = 'Model Analyzer';
            app.CAUTIONThismightbeamorecomplextoolthanyouneedbeawareLabel = uilabel(app.CustomModelTab);
            app.CAUTIONThismightbeamorecomplextoolthanyouneedbeawareLabel.Text = {'CAUTION: This might be a more'; 'complex tool than you need...be aware'};
            app.InnateImmuneResponsetoSARSCoV2InfectioninHumansLabel = uilabel(app.ModelManipulationTab);
            app.InnateImmuneResponsetoSARSCoV2InfectioninHumansLabel.Text = 'Innate Immune Response to SARS-CoV-2 Infection in Humans';
            app.PatrickCharltonHannahRuMabryStuartMcKnightTrevorWoodruffLabel = uilabel(app.ModelManipulationTab);
            app.PatrickCharltonHannahRuMabryStuartMcKnightTrevorWoodruffLabel.Text = 'Patrick Charlton, Hannah (Ru) Mabry, Stuart McKnight, Trevor Woodruff';
            app.VirusImage = uiimage(app.ModelManipulationTab);
            app.VirusImage.ImageSource = 'coronavirus-covid-19.png';
            app.ParameterModification = uitab(app.GUITabs);
            app.ParameterModification.Title = 'Parameter Modification';
            app.ParameterTable = uitable(app.ParameterModification);
            app.ParameterTable.ColumnName = {'#'; 'Reaction'; 'Rate Constant Name'; 'Value'; 'Units'};
            app.ParameterTable.RowName = {};
            app.ParameterTable.ColumnSortable = [true true true true false];
            app.ParameterTable.ColumnEditable = [false false false true false];
            app.ParameterTable.CellEditCallback = createCallbackFcn(app, @ParameterTableCellEdit, true);
            app.ParametersLabel = uilabel(app.ParameterModification);
            app.ParametersLabel.Text = 'Parameters';
            app.UpdateParametersButton = uibutton(app.ParameterModification, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = {'Update Parameters'; ''};
            app.SpeciesTable = uitable(app.ParameterModification);
            app.SpeciesTable.ColumnName = {'#'; 'Name'; 'Value'; 'Units'};
            app.SpeciesTable.RowName = {};
            app.SpeciesTable.ColumnSortable = true;
            app.SpeciesTable.ColumnEditable = [false false true];
            app.SpeciesTable.CellEditCallback = createCallbackFcn(app, @SpeciesTableCellEdit, true);
            app.UpdateSpeciesButton = uibutton(app.ParameterModification, 'push');
            app.UpdateSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSpeciesButtonPushed, true);
            app.UpdateSpeciesButton.Text = 'Update Species';
            app.SpeciesLabel = uilabel(app.ParameterModification);
            app.SpeciesLabel.Text = 'Species';
            app.SolvingtheModelTab = uitab(app.GUITabs);
            app.SolvingtheModelTab.Title = 'Solving the Model';
            app.SolvingtheModelLabel = uilabel(app.SolvingtheModelTab);
            app.SolvingtheModelLabel.Text = 'Solving the Model';
            app.SpecifyInitialViralExposurePanel = uipanel(app.SolvingtheModelTab);
            app.SpecifyInitialViralExposurePanel.Title = 'Specify Initial Viral Exposure';
            app.ViralDoseExposureEditFieldLabel = uilabel(app.SpecifyInitialViralExposurePanel);
            app.ViralDoseExposureEditFieldLabel.Text = 'Viral Dose Exposure';
            app.ViralDoseExposureEditField = uieditfield(app.SpecifyInitialViralExposurePanel, 'numeric');
            app.ViralDoseExposureEditField.Limits = [0 Inf];
            app.ViralDoseExposureEditField.Value = 10000;
            app.TimeofViralExposureEditFieldLabel = uilabel(app.SpecifyInitialViralExposurePanel);
            app.TimeofViralExposureEditFieldLabel.Text = 'Time of Viral Exposure';
            app.TimeofViralExposureEditField = uieditfield(app.SpecifyInitialViralExposurePanel, 'numeric');
            app.TimeofViralExposureEditField.Limits = [0 Inf];
            app.TimeofViralExposureEditField.Value = 150;
            app.VirusInBodyBeforeExposureEditFieldLabel = uilabel(app.SpecifyInitialViralExposurePanel);
            app.VirusInBodyBeforeExposureEditFieldLabel.Text = 'Virus In Body (Before Exposure)';
            app.VirusInBodyBeforeExposureEditField = uieditfield(app.SpecifyInitialViralExposurePanel, 'numeric');
            app.VirusInBodyBeforeExposureEditField.Limits = [0 Inf];
            app.AddViralDoseButton = uibutton(app.SpecifyInitialViralExposurePanel, 'push');
            app.AddViralDoseButton.ButtonPushedFcn = createCallbackFcn(app, @AddViralDoseButtonPushed, true);
            app.AddViralDoseButton.Text = 'Add Viral Dose';
            app.RemoveViralDoseButton = uibutton(app.SpecifyInitialViralExposurePanel, 'push');
            app.RemoveViralDoseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveViralDoseButtonPushed, true);
            app.RemoveViralDoseButton.Text = 'Remove Viral Dose';
            app.hoursLabel = uilabel(app.SpecifyInitialViralExposurePanel);
            app.hoursLabel.Text = 'hours';
            app.molarLabel = uilabel(app.SpecifyInitialViralExposurePanel);
            app.molarLabel.Text = 'molar';
            app.viruscellsLabel = uilabel(app.SpecifyInitialViralExposurePanel);
            app.viruscellsLabel.Text = 'virus cells';
            app.RunModelSimulationPanel = uipanel(app.SolvingtheModelTab);
            app.RunModelSimulationPanel.Title = 'Run Model Simulation';
            app.RunModelSimulationButton = uibutton(app.RunModelSimulationPanel, 'push');
            app.RunModelSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelSimulationButtonPushed, true);
            app.RunModelSimulationButton.Text = 'Run Model Simulation';
            app.ResultsFileNameEditFieldLabel = uilabel(app.RunModelSimulationPanel);
            app.ResultsFileNameEditFieldLabel.Text = 'Results File Name';
            app.ResultsFileNameEditField = uieditfield(app.RunModelSimulationPanel, 'text');
            app.ExportasCSVButton = uibutton(app.RunModelSimulationPanel, 'push');
            app.ExportasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasCSVButtonPushed, true);
            app.ExportasCSVButton.Text = 'Export as CSV';
            app.ModelSolvingUpdate = uieditfield(app.RunModelSimulationPanel, 'text');
            app.ModelSolvingUpdate.Editable = 'off';
            app.SolverSetup = uitabgroup(app.SolvingtheModelTab);
            app.DefaultSolverTab = uitab(app.SolverSetup);
            app.DefaultSolverTab.Title = 'Default Solver';
            app.SpecifyandUseDefaultSettingsLabel = uilabel(app.DefaultSolverTab);
            app.SpecifyandUseDefaultSettingsLabel.Text = 'Specify and Use Default Settings';
            app.StopTimeEditFieldLabel = uilabel(app.DefaultSolverTab);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditFieldDefault = uieditfield(app.DefaultSolverTab, 'numeric');
            app.StopTimeEditFieldDefault.Limits = [0 Inf];
            app.StopTimeEditFieldDefault.Value = 80;
            app.SetDefualtSolverButton = uibutton(app.DefaultSolverTab, 'push');
            app.SetDefualtSolverButton.ButtonPushedFcn = createCallbackFcn(app, @SetDefualtSolverButtonPushed, true);
            app.SetDefualtSolverButton.Text = 'Set Defualt Solver';
            app.hoursLabel_2 = uilabel(app.DefaultSolverTab);
            app.hoursLabel_2.Text = 'hours';
            app.AdvanceSolverSetupTab = uitab(app.SolverSetup);
            app.AdvanceSolverSetupTab.Title = 'Advance Solver Setup';
            app.SpecifyAdvancedSolverParametersLabel = uilabel(app.AdvanceSolverSetupTab);
            app.SpecifyAdvancedSolverParametersLabel.Text = 'Specify Advanced Solver Parameters';
            app.AbsoluteToleranceSclaingDropDownLabel = uilabel(app.AdvanceSolverSetupTab);
            app.AbsoluteToleranceSclaingDropDownLabel.Text = 'Absolute Tolerance Sclaing';
            app.AbsoluteToleranceSclaingDropDown = uidropdown(app.AdvanceSolverSetupTab);
            app.AbsoluteToleranceSclaingDropDown.Items = {'On', 'Off'};
            app.AbsoluteToleranceSclaingDropDown.Value = 'Off';
            app.SetAdvancedSettingsButton = uibutton(app.AdvanceSolverSetupTab, 'push');
            app.SetAdvancedSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SetAdvancedSettingsButtonPushed, true);
            app.SetAdvancedSettingsButton.Text = {'Set Advanced Settings'; ''};
            app.SolverOptionsLabel = uilabel(app.AdvanceSolverSetupTab);
            app.SolverOptionsLabel.Text = 'Solver Options';
            app.SolverTypeDropDownLabel = uilabel(app.AdvanceSolverSetupTab);
            app.SolverTypeDropDownLabel.Text = 'Solver Type';
            app.SolverTypeDropDown = uidropdown(app.AdvanceSolverSetupTab);
            app.SolverTypeDropDown.Items = {'ode15s', 'ode45', 'ode23t', 'ssa', 'sundials', 'expltau', 'impltau'};
            app.SolverTypeDropDown.Value = 'ode15s';
            app.AbsoluteToleranceEditFieldLabel = uilabel(app.AdvanceSolverSetupTab);
            app.AbsoluteToleranceEditFieldLabel.Text = 'Absolute Tolerance';
            app.AbsoluteToleranceEditField = uieditfield(app.AdvanceSolverSetupTab, 'numeric');
            app.AbsoluteToleranceEditField.Limits = [0 Inf];
            app.AbsoluteToleranceEditField.Value = 1e-06;
            app.RelativeToleranceEditFieldLabel = uilabel(app.AdvanceSolverSetupTab);
            app.RelativeToleranceEditFieldLabel.Text = 'Relative Tolerance';
            app.RelativeToleranceEditField = uieditfield(app.AdvanceSolverSetupTab, 'numeric');
            app.RelativeToleranceEditField.Limits = [0 Inf];
            app.RelativeToleranceEditField.Value = 0.001;
            app.SensitivityAnalysisDropDownLabel = uilabel(app.AdvanceSolverSetupTab);
            app.SensitivityAnalysisDropDownLabel.Text = 'Sensitivity Analysis';
            app.SensitivityAnalysisDropDown = uidropdown(app.AdvanceSolverSetupTab);
            app.SensitivityAnalysisDropDown.Items = {'On', 'Off'};
            app.SensitivityAnalysisDropDown.Value = 'Off';
            app.StopTimeLabel = uilabel(app.AdvanceSolverSetupTab);
            app.StopTimeLabel.Text = 'Stop Time';
            app.StopTimeEditFieldAdvanced = uieditfield(app.AdvanceSolverSetupTab, 'numeric');
            app.StopTimeEditFieldAdvanced.Limits = [0 Inf];
            app.StopTimeEditFieldAdvanced.Value = 300;
            app.WallClocksecEditFieldLabel = uilabel(app.AdvanceSolverSetupTab);
            app.WallClocksecEditFieldLabel.Text = 'Wall Clock (sec.)';
            app.WallClockEditField = uieditfield(app.AdvanceSolverSetupTab, 'numeric');
            app.WallClockEditField.Limits = [0 Inf];
            app.WallClockEditField.Value = 120;
            app.ModelFeatures = uitabgroup(app.SolvingtheModelTab);
            app.SolverTab = uitab(app.ModelFeatures);
            app.SolverTab.Title = 'Solver';
            app.SolverTypeLabel = uilabel(app.SolverTab);
            app.SolverTypeLabel.Text = 'Solver Type';
            app.ConfirmSolverTypeEditField = uieditfield(app.SolverTab, 'text');
            app.ConfirmSolverTypeEditField.Editable = 'off';
            app.StopTimeLabel_2 = uilabel(app.SolverTab);
            app.StopTimeLabel_2.Text = 'Stop Time';
            app.ConfirmStopTimeEditField = uieditfield(app.SolverTab, 'text');
            app.ConfirmStopTimeEditField.Editable = 'off';
            app.ConfigurationLabel = uilabel(app.SolverTab);
            app.ConfigurationLabel.Text = 'Configuration';
            app.SolverOptionsLabel_2 = uilabel(app.SolverTab);
            app.SolverOptionsLabel_2.Text = 'Solver Options';
            app.AbsolutToleranceLabel = uilabel(app.SolverTab);
            app.AbsolutToleranceLabel.Text = 'Absolut Tolerance';
            app.ConfirmAbsoluteToleranceEditField = uieditfield(app.SolverTab, 'text');
            app.ConfirmAbsoluteToleranceEditField.Editable = 'off';
            app.AbsoluteToleranceScalingLabel = uilabel(app.SolverTab);
            app.AbsoluteToleranceScalingLabel.Text = 'Absolute Tolerance Scaling';
            app.ConfirmAbsoluteToleranceScalingEditField = uieditfield(app.SolverTab, 'text');
            app.ConfirmAbsoluteToleranceScalingEditField.Editable = 'off';
            app.RelativeToleranceLabel = uilabel(app.SolverTab);
            app.RelativeToleranceLabel.Text = 'Relative Tolerance';
            app.ConfirmRelativeToleranceEditField = uieditfield(app.SolverTab, 'text');
            app.ConfirmRelativeToleranceEditField.Editable = 'off';
            app.SensitivityAnalysisLabel = uilabel(app.SolverTab);
            app.SensitivityAnalysisLabel.Text = 'Sensitivity Analysis';
            app.ConfirmSensitivityAnalysisEditField = uieditfield(app.SolverTab, 'text');
            app.ConfirmSensitivityAnalysisEditField.Editable = 'off';
            app.WallClockLabel = uilabel(app.SolverTab);
            app.WallClockLabel.Text = 'Wall Clock';
            app.ConfirmWallClockEditField = uieditfield(app.SolverTab, 'text');
            app.ConfirmWallClockEditField.Editable = 'off';
            app.ThesearetheoptionsyouarecurrentlyrunningLabel = uilabel(app.SolverTab);
            app.ThesearetheoptionsyouarecurrentlyrunningLabel.Text = 'These are the options you are currently running';
            app.ExposureTab = uitab(app.ModelFeatures);
            app.ExposureTab.Title = 'Exposure';
            app.InitialVirusInBodyLabel = uilabel(app.ExposureTab);
            app.InitialVirusInBodyLabel.Text = 'Initial Virus In Body';
            app.ConfirmVirusInBodyEditField = uieditfield(app.ExposureTab, 'text');
            app.ConfirmVirusInBodyEditField.Editable = 'off';
            app.InitialVirusLabel = uilabel(app.ExposureTab);
            app.InitialVirusLabel.Text = 'Initial Virus';
            app.ViralExposureLabel = uilabel(app.ExposureTab);
            app.ViralExposureLabel.Text = 'Viral Exposure';
            app.ViralDoseLabel = uilabel(app.ExposureTab);
            app.ViralDoseLabel.Text = 'Viral Dose';
            app.ConfirmViralDoseAmountEditField = uieditfield(app.ExposureTab, 'text');
            app.ConfirmViralDoseAmountEditField.Editable = 'off';
            app.TimeofExposureLabel = uilabel(app.ExposureTab);
            app.TimeofExposureLabel.Text = 'Time of Exposure';
            app.ConfirmTimeOfExposureEditField = uieditfield(app.ExposureTab, 'text');
            app.ConfirmTimeOfExposureEditField.Editable = 'off';
            app.TargetSpeciesLabel = uilabel(app.ExposureTab);
            app.TargetSpeciesLabel.Text = 'Target Species';
            app.ConfirmTargetSpeciesEditField = uieditfield(app.ExposureTab, 'text');
            app.ConfirmTargetSpeciesEditField.Editable = 'off';
            app.ThesearetheexposureoptionsyouarecurrentlyrunningLabel = uilabel(app.ExposureTab);
            app.ThesearetheexposureoptionsyouarecurrentlyrunningLabel.Text = 'These are the exposure options you are currently running';
            app.PlottingTab = uitab(app.ModelFeatures);
            app.PlottingTab.Title = 'Plotting';
            app.XMinSliderLabel = uilabel(app.PlottingTab);
            app.XMinSliderLabel.Text = 'X Min';
            app.XMinSlider = uislider(app.PlottingTab);
            app.XMinSlider.ValueChangedFcn = createCallbackFcn(app, @XMinSliderValueChanged, true);
            app.XMinSlider.ValueChangingFcn = createCallbackFcn(app, @XMinSliderValueChanged, true);
            app.XMaxSliderLabel = uilabel(app.PlottingTab);
            app.XMaxSliderLabel.Text = 'X Max';
            app.XMaxSlider = uislider(app.PlottingTab);
            app.XMaxSlider.ValueChangedFcn = createCallbackFcn(app, @XMaxSliderValueChanged, true);
            app.XMaxSlider.ValueChangingFcn = createCallbackFcn(app, @XMaxSliderValueChanged, true);
            app.ConcOrCountDropDown = uidropdown(app.PlottingTab);
            app.ConcOrCountDropDown.Items = {'Concentration', 'Count'};
            app.ConcOrCountDropDown.ItemsData = {'Conc', 'Count'};
            app.ConcOrCountDropDown.ValueChangedFcn = createCallbackFcn(app, @ConcOrCountDropDownValueChanged, true);
            app.ConcOrCountDropDown.Value = 'Conc';
            app.SeeLargerPlotButton = uibutton(app.PlottingTab, 'push');
            app.SeeLargerPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeeLargerPlotButtonPushed, true);
            app.SeeLargerPlotButton.Text = 'See Larger Plot';
            app.UIAxes = uiaxes(app.PlottingTab);
            title(app.UIAxes, 'Extracellular Virus')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PlottingtheModelTab = uitab(app.GUITabs);
            app.PlottingtheModelTab.Title = 'Plotting the Model';
            app.XMinSlider_2Label = uilabel(app.PlottingtheModelTab);
            app.XMinSlider_2Label.Text = 'X Min';
            app.XMinSpeciesPlot = uislider(app.PlottingtheModelTab);
            app.XMinSpeciesPlot.ValueChangedFcn = createCallbackFcn(app, @XMinSpeciesPlotValueChanged, true);
            app.XMaxSlider_2Label = uilabel(app.PlottingtheModelTab);
            app.XMaxSlider_2Label.Text = 'X Max';
            app.XMaxSpeciesPlot = uislider(app.PlottingtheModelTab);
            app.XMaxSpeciesPlot.ValueChangedFcn = createCallbackFcn(app, @XMinSpeciesPlotValueChanged, true);
            app.YMinSliderLabel = uilabel(app.PlottingtheModelTab);
            app.YMinSliderLabel.Text = 'Y Min';
            app.YMinSpeciesPlot = uislider(app.PlottingtheModelTab);
            app.YMinSpeciesPlot.ValueChangedFcn = createCallbackFcn(app, @YMinSpeciesPlotValueChanged, true);
            app.YMaxSliderLabel = uilabel(app.PlottingtheModelTab);
            app.YMaxSliderLabel.Text = 'Y Max';
            app.YMaxSpeciesPlot = uislider(app.PlottingtheModelTab);
            app.YMaxSpeciesPlot.ValueChangedFcn = createCallbackFcn(app, @YMinSpeciesPlotValueChanged, true);
            app.PlotTypeTab = uitabgroup(app.PlottingtheModelTab);
            app.DefaultPlotTab = uitab(app.PlotTypeTab);
            app.DefaultPlotTab.Title = 'Default';
            app.PlotDefaultButton = uibutton(app.DefaultPlotTab, 'push');
            app.PlotDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDefaultButtonPushed, true);
            app.PlotDefaultButton.Text = 'Plot Default';
            app.MasterPlotDefaultConcOrCountDropDown = uidropdown(app.DefaultPlotTab);
            app.MasterPlotDefaultConcOrCountDropDown.Items = {'Concentration', 'Count'};
            app.MasterPlotDefaultConcOrCountDropDown.ItemsData = {'Conc', 'Count'};
            app.MasterPlotDefaultConcOrCountDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDefaultButtonPushed, true);
            app.MasterPlotDefaultConcOrCountDropDown.Value = 'Conc';
            app.CustomSpeciesPlotTab = uitab(app.PlotTypeTab);
            app.CustomSpeciesPlotTab.Title = 'Custom Species';
            app.SelectSpeciesctrlcmdtoselectmultipleLabel = uilabel(app.CustomSpeciesPlotTab);
            app.SelectSpeciesctrlcmdtoselectmultipleLabel.Text = 'Select Species  (ctrl/cmd to select multiple)';
            app.Species = uilistbox(app.CustomSpeciesPlotTab);
            app.Species.Items = {};
            app.Species.Multiselect = 'on';
            app.Species.ValueChangedFcn = createCallbackFcn(app, @SpeciesValueChanged, true);
            app.Species.Value = {};
            app.MasterPlotCustomConcOrCountDropDown = uidropdown(app.CustomSpeciesPlotTab);
            app.MasterPlotCustomConcOrCountDropDown.Items = {'Concentration', 'Count'};
            app.MasterPlotCustomConcOrCountDropDown.ItemsData = {'Conc', 'Count'};
            app.MasterPlotCustomConcOrCountDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesValueChanged, true);
            app.MasterPlotCustomConcOrCountDropDown.Value = 'Conc';
            app.ExportPlotButton = uibutton(app.PlottingtheModelTab, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = 'Export Plot';
            app.SavePlotButton = uibutton(app.PlottingtheModelTab, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.FilenameEditFieldLabel = uilabel(app.PlottingtheModelTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.SavePlotFileNameEditField = uieditfield(app.PlottingtheModelTab, 'text');
            app.AxesSpeciesPlot = uiaxes(app.PlottingtheModelTab);
            title(app.AxesSpeciesPlot, 'SARS CoV 2 Time Course in a Human')
            zlabel(app.AxesSpeciesPlot, 'Z')
            app.VisualSensitivityTab = uitab(app.GUITabs);
            app.VisualSensitivityTab.Title = 'Visual Sensitivity';
            app.SensPlottingTab = uitabgroup(app.VisualSensitivityTab);
            app.SensitivitySolverTab = uitab(app.SensPlottingTab);
            app.SensitivitySolverTab.Title = 'Sensitivity Solver';
            app.SensParamTable = uitable(app.SensitivitySolverTab);
            app.SensParamTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SensParamTable.RowName = {};
            app.SensParamTable.ColumnEditable = [false true true true];
            app.SensParamTable.CellEditCallback = createCallbackFcn(app, @SensParamTableCellEdit, true);
            app.UpdateSolverButton = uibutton(app.SensitivitySolverTab, 'push');
            app.UpdateSolverButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSolverButtonPushed, true);
            app.UpdateSolverButton.Text = 'Update Solver';
            app.UpdateExposureButton = uibutton(app.SensitivitySolverTab, 'push');
            app.UpdateExposureButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateExposureButtonPushed, true);
            app.UpdateExposureButton.Text = 'Update Exposure';
            app.ResetParametersButton = uibutton(app.SensitivitySolverTab, 'push');
            app.ResetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetParametersButtonPushed, true);
            app.ResetParametersButton.Text = 'Reset Parameters';
            app.RUNButton = uibutton(app.SensitivitySolverTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ModifyParameterValuesSameUnitsasAboveLabel = uilabel(app.SensitivitySolverTab);
            app.ModifyParameterValuesSameUnitsasAboveLabel.Text = 'Modify Parameter Values (Same Units as Above)';
            app.AddRowSensParamsTableButton = uibutton(app.SensitivitySolverTab, 'push');
            app.AddRowSensParamsTableButton.Visible = 'off';
            app.AddRowSensParamsTableButton.Text = '+';
            app.RemoveRowSensParamsTableButton = uibutton(app.SensitivitySolverTab, 'push');
            app.RemoveRowSensParamsTableButton.Visible = 'off';
            app.RemoveRowSensParamsTableButton.Text = '-';
            app.SelectParametersctrlcmdtoselectmultipleLabel = uilabel(app.SensitivitySolverTab);
            app.SelectParametersctrlcmdtoselectmultipleLabel.Text = 'Select Parameters  (ctrl/cmd to select multiple)';
            app.SensParameters = uilistbox(app.SensitivitySolverTab);
            app.SensParameters.Items = {};
            app.SensParameters.Multiselect = 'on';
            app.SensParameters.ValueChangedFcn = createCallbackFcn(app, @SensParametersValueChanged, true);
            app.SensParameters.Value = {};
            app.SensSolvingUpdateEditField = uieditfield(app.SensitivitySolverTab, 'text');
            app.SelectingtoomanyparameterscanresultinlongruntimesLabel = uilabel(app.SensitivitySolverTab);
            app.SelectingtoomanyparameterscanresultinlongruntimesLabel.Text = 'Selecting too many parameters can result in long run times';
            app.SensSpeciesToPlotTab = uitab(app.SensPlottingTab);
            app.SensSpeciesToPlotTab.Title = 'Species To Plot';
            app.SelectSpeciesLabel = uilabel(app.SensSpeciesToPlotTab);
            app.SelectSpeciesLabel.Text = 'Select Species';
            app.SensSpecies = uilistbox(app.SensSpeciesToPlotTab);
            app.SensSpecies.Items = {};
            app.SensSpecies.ValueChangedFcn = createCallbackFcn(app, @SensSpeciesValueChanged, true);
            app.SensSpecies.Value = {};
            app.SensPlotConcOrCountDropDown = uidropdown(app.VisualSensitivityTab);
            app.SensPlotConcOrCountDropDown.Items = {'Concentration', 'Count'};
            app.SensPlotConcOrCountDropDown.ItemsData = {'Conc', 'Count'};
            app.SensPlotConcOrCountDropDown.ValueChangedFcn = createCallbackFcn(app, @SensPlotConcOrCountDropDownValueChanged, true);
            app.SensPlotConcOrCountDropDown.Value = 'Conc';
            app.ExportSensPlotButton = uibutton(app.VisualSensitivityTab, 'push');
            app.ExportSensPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSensPlotButtonPushed, true);
            app.ExportSensPlotButton.Text = 'Export Plot';
            app.SaveSensPlotButton = uibutton(app.VisualSensitivityTab, 'push');
            app.SaveSensPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSensPlotButtonPushed, true);
            app.SaveSensPlotButton.Text = 'Save Plot';
            app.YMaxSliderLabel_2 = uilabel(app.VisualSensitivityTab);
            app.YMaxSliderLabel_2.Text = 'Y Max';
            app.YMaxSensPlot = uislider(app.VisualSensitivityTab);
            app.YMaxSensPlot.ValueChangedFcn = createCallbackFcn(app, @YMaxSensPlotValueChanged, true);
            app.FilenameEditFieldLabel_2 = uilabel(app.VisualSensitivityTab);
            app.FilenameEditFieldLabel_2.Text = 'Filename';
            app.SaveSensPlotFileNameEditField = uieditfield(app.VisualSensitivityTab, 'text');
            app.XMinSensPlot = uislider(app.VisualSensitivityTab);
            app.XMinSensPlot.ValueChangedFcn = createCallbackFcn(app, @XMinSensPlotValueChanged, true);
            app.XMinSlider_2Label_2 = uilabel(app.VisualSensitivityTab);
            app.XMinSlider_2Label_2.Text = 'X Min';
            app.XMaxSensPlot = uislider(app.VisualSensitivityTab);
            app.XMaxSensPlot.ValueChangedFcn = createCallbackFcn(app, @XMaxSensPlotValueChanged, true);
            app.XMaxSlider_2Label_2 = uilabel(app.VisualSensitivityTab);
            app.XMaxSlider_2Label_2.Text = 'X Max';
            app.YMaxSliderLabel_3 = uilabel(app.VisualSensitivityTab);
            app.YMaxSliderLabel_3.Text = 'Y Min';
            app.YMinSensPlot = uislider(app.VisualSensitivityTab);
            app.YMinSensPlot.ValueChangedFcn = createCallbackFcn(app, @YMinSensPlotValueChanged, true);
            app.UISensAxes = uiaxes(app.VisualSensitivityTab);
            title(app.UISensAxes, 'Visual Sensitivity: Dynamics of the System')
            xlabel(app.UISensAxes, 'X')
            ylabel(app.UISensAxes, 'Y')
            zlabel(app.UISensAxes, 'Z')
            % Show the figure after all components are created
            app.SARSCoV2ModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v8_SARS_CoV_2_1AprSM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SARSCoV2ModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SARSCoV2ModelUIFigure)
        end
    end
end